classdef boxplot_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure               matlab.ui.Figure
		FileMenu               matlab.ui.container.Menu
		OpenfileMenu           matlab.ui.container.Menu
		GridLayout             matlab.ui.container.GridLayout
		LeftPanel              matlab.ui.container.Panel
		YaxissettingsPanel     matlab.ui.container.Panel
		Spinner_YaxisRounding  matlab.ui.control.Spinner
		RoundtoLabel           matlab.ui.control.Label
		Spinner_YaxisTicks     matlab.ui.control.Spinner
		TicksLabel             matlab.ui.control.Label
		DebugText              matlab.ui.control.TextArea
		DropDown_SelectMetric  matlab.ui.control.DropDown
		MetricLabel            matlab.ui.control.Label
		RightPanel             matlab.ui.container.Panel
		UIAxes_max             matlab.ui.control.UIAxes
		UIAxes_min             matlab.ui.control.UIAxes
		UIAxes_std             matlab.ui.control.UIAxes
		UIAxes_mean            matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = private)
		m_data % Description
		m_class
		m_lidarNames = ["Hmax", "Hmean","Hmedian","Hp25","Hp75", "Hp95"...
			  "PPR","DAM_z","BR_bellow_1","BR_1_2","BR_2_3","BR_above_3","BR_3_4","BR_4_5","BR_bellow_5","BR_5_20","BR_above_20" ...
			  "Coeff_var_z", "Hkurt", "Hskew", "Hstd", "Hvar"]
	end
	
	methods (Access = private)
		
		function results = redraw_graphs(app)
			roundTo = app.Spinner_YaxisRounding.Value;
			nTicks = app.Spinner_YaxisTicks.Value;
			value = app.DropDown_SelectMetric.Value;
			n = find(contains(app.m_lidarNames, value));
			
			data = app.m_data(:, 4*(n-1) + 1);
			boxplot(app.UIAxes_mean, data , app.m_class, 'Labels', {'91E0', '91F0','91G0','9110','Mono'})
			yticks(app.UIAxes_mean, round(linspace(min(data), max(data), nTicks), roundTo))
			title(app.UIAxes_mean, value + "_mean", 'interpreter', 'none')
			
			data = app.m_data(:, 4*(n-1) + 2);
			boxplot(app.UIAxes_std, data , app.m_class, 'Labels', {'91E0', '91F0','91G0','9110','Mono'})
			yticks(app.UIAxes_std, round(linspace(min(data), max(data), nTicks), roundTo))
			title(app.UIAxes_std, value + "_std", 'interpreter', 'none')
			
			data = app.m_data(:, 4*(n-1) + 3);
			boxplot(app.UIAxes_min, data , app.m_class, 'Labels', {'91E0', '91F0','91G0','9110','Mono'})
			yticks(app.UIAxes_min, round(linspace(min(data), max(data), nTicks), roundTo))
			title(app.UIAxes_min, value + "_min", 'interpreter', 'none')
			
			data = app.m_data(:, 4*(n-1) + 4);
			boxplot(app.UIAxes_max, data , app.m_class, 'Labels', {'91E0', '91F0','91G0','9110','Mono'})
			yticks(app.UIAxes_max, round(linspace(min(data), max(data), nTicks), roundTo))
			title(app.UIAxes_max, value + "_max", 'interpreter', 'none')

			results = true;
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Value changed function: DropDown_SelectMetric
		function DropDown_SelectMetricValueChanged(app, event)
			redraw_graphs(app);
			
		end

		% Menu selected function: OpenfileMenu
		function OpenfileMenuSelected(app, event)
			[fileName, filePath] = uigetfile('*.csv', 'Select CSV file'); % .osm file path
			filePath = fullfile(filePath, fileName);

			data = readmatrix(filePath);
			app.m_class = data(:, end);
			app.m_data = data(:, 1:end-1);

			app.DebugText.Value = app.DebugText.Value + "File loaded";

			redraw_graphs(app);
		end

		% Value changed function: Spinner_YaxisTicks
		function Spinner_YaxisTicksValueChanged(app, event)
			redraw_graphs(app);
			
		end

		% Value changed function: Spinner_YaxisRounding
		function Spinner_YaxisRoundingValueChanged(app, event)
			redraw_graphs(app);
			
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {731, 731};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {219, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.FileMenu = uimenu(app.UIFigure);
			app.FileMenu.Text = 'File';
			app.OpenfileMenu = uimenu(app.FileMenu);
			app.OpenfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfileMenuSelected, true);
			app.OpenfileMenu.Accelerator = 'O';
			app.OpenfileMenu.Text = 'Open file';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.MetricLabel = uilabel(app.LeftPanel);
			app.MetricLabel.Text = 'Metric:';
			app.DropDown_SelectMetric = uidropdown(app.LeftPanel);
			app.DropDown_SelectMetric.Items = {'Hmax', 'Hmean', 'Hmedian', 'Hp25', 'Hp75', 'Hp95', 'PPR', 'DAM_z', 'BR_bellow_1', 'BR_1_2', 'BR_2_3', 'BR_above_3', 'BR_3_4', 'BR_4_5', 'BR_bellow_5', 'BR_5_20', 'BR_above_20', 'Coeff_var_z', 'Hkurt', 'Hskew', 'Hstd', 'Hvar'};
			app.DropDown_SelectMetric.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectMetricValueChanged, true);
			app.DropDown_SelectMetric.Value = 'Hmax';
			app.DebugText = uitextarea(app.LeftPanel);
			app.YaxissettingsPanel = uipanel(app.LeftPanel);
			app.YaxissettingsPanel.Title = 'Y axis settings';
			app.TicksLabel = uilabel(app.YaxissettingsPanel);
			app.TicksLabel.Text = 'Ticks:';
			app.Spinner_YaxisTicks = uispinner(app.YaxissettingsPanel);
			app.Spinner_YaxisTicks.Limits = [1 100];
			app.Spinner_YaxisTicks.ValueChangedFcn = createCallbackFcn(app, @Spinner_YaxisTicksValueChanged, true);
			app.Spinner_YaxisTicks.Value = 9;
			app.RoundtoLabel = uilabel(app.YaxissettingsPanel);
			app.RoundtoLabel.Text = 'Round to:';
			app.Spinner_YaxisRounding = uispinner(app.YaxissettingsPanel);
			app.Spinner_YaxisRounding.Limits = [1 5];
			app.Spinner_YaxisRounding.ValueChangedFcn = createCallbackFcn(app, @Spinner_YaxisRoundingValueChanged, true);
			app.Spinner_YaxisRounding.Value = 3;
			app.RightPanel = uipanel(app.GridLayout);
			app.UIAxes_mean = uiaxes(app.RightPanel);
			title(app.UIAxes_mean, 'Title')
			xlabel(app.UIAxes_mean, 'Class')
			ylabel(app.UIAxes_mean, 'Metric values')
			zlabel(app.UIAxes_mean, 'Z')
			app.UIAxes_mean.XTick = [];
			app.UIAxes_mean.XTickLabel = '';
			app.UIAxes_mean.YTick = [];
			app.UIAxes_std = uiaxes(app.RightPanel);
			title(app.UIAxes_std, 'Title')
			xlabel(app.UIAxes_std, 'Class')
			ylabel(app.UIAxes_std, 'Metric values')
			zlabel(app.UIAxes_std, 'Z')
			app.UIAxes_std.XTick = [];
			app.UIAxes_std.XTickLabel = '';
			app.UIAxes_std.YTick = [];
			app.UIAxes_min = uiaxes(app.RightPanel);
			title(app.UIAxes_min, 'Title')
			xlabel(app.UIAxes_min, 'Class')
			ylabel(app.UIAxes_min, 'Metric values')
			zlabel(app.UIAxes_min, 'Z')
			app.UIAxes_min.XTick = [];
			app.UIAxes_min.XTickLabel = '';
			app.UIAxes_min.YTick = [];
			app.UIAxes_max = uiaxes(app.RightPanel);
			title(app.UIAxes_max, 'Title')
			xlabel(app.UIAxes_max, 'Class')
			ylabel(app.UIAxes_max, 'Metric values')
			zlabel(app.UIAxes_max, 'Z')
			app.UIAxes_max.XTick = [];
			app.UIAxes_max.XTickLabel = '';
			app.UIAxes_max.YTick = [];
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = boxplot_viewer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end